classdef methodSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NumiiSolveLabel                 matlab.ui.control.Label
        MathproblemIdoitforyouLabel     matlab.ui.control.Label
        ChooseyourtypeofproblemButtonGroup  matlab.ui.container.ButtonGroup
        NolinearequationButton_2        matlab.ui.control.ToggleButton
        NumericalintegrationButton_2    matlab.ui.control.ToggleButton
        NolinearequationsystemButton_2  matlab.ui.control.ToggleButton
        DiferentialequationsystemButton_2  matlab.ui.control.ToggleButton
        ListBox                         matlab.ui.control.ListBox
        ListBox_2                       matlab.ui.control.ListBox
        ListBox_3                       matlab.ui.control.ListBox
        ListBox_4                       matlab.ui.control.ListBox
        UIAxes                          matlab.ui.control.UIAxes
        EcuationEditField               matlab.ui.control.EditField
        RangeofIntervalLabel            matlab.ui.control.Label
        MinEditField_2Label             matlab.ui.control.Label
        MinEditField_2                  matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        MaxEditField_2                  matlab.ui.control.NumericEditField
        IterationsLabel                 matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        RaizLabel                       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        EaLabel                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        EcuationxLabel                  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        XSpinnerLabel                   matlab.ui.control.Label
        XSpinner                        matlab.ui.control.Spinner
        UITable3                        matlab.ui.control.Table
        InitialvalueEditFieldLabel      matlab.ui.control.Label
        InitialvalueEditField           matlab.ui.control.NumericEditField
        DerivativeLabel                 matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        AXLabel                         matlab.ui.control.Label
        BLabel                          matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = func(app)
            % Everything disappears and the values are reset
            app.ListBox_4.Visible = false;
            app.ListBox_3.Visible = false;
            app.ListBox_2.Visible = false;
            app.ListBox.Visible = false;
            % Answer
            app.Label.Visible = false;
            app.Label_2.Visible = false;
            app.RaizLabel.Visible = false;
            app.EaLabel.Visible = false;
            % Graph
            app.UIAxes.Visible = false;
            % We need a way to delete the data from the graph
            % Tables
            app.UITable.Visible = false;
            app.UITable.Enable = 'on';
            app.UITable.Data = zeros(2);
            app.UITable2.Visible = false;
            app.UITable2.Enable = 'on';
            app.UITable2.Data = zeros(2,1);
            app.UITable3.Visible = false;
            app.AXLabel.Visible = false;
            app.BLabel.Visible = false;
            app.UITable.ColumnEditable = true;
            app.UITable2.ColumnEditable = true;
            % x#
            app.XSpinner.Visible = false;
            app.XSpinner.Value = 2;
            app.XSpinnerLabel.Visible = false;
            % Initail value
            app.InitialvalueEditFieldLabel.Visible = false;
            app.InitialvalueEditField.Visible = false;
            app.InitialvalueEditField.Value = 0;
            % Ecuation
            app.EcuationEditField.Visible = false;
            app.EcuationxLabel.Visible = false;
            app.EcuationEditField.Value = "";
            % Range
            app.MaxEditField_2.Visible = false;
            app.MaxEditField_2Label.Visible = false;
            app.MaxEditField_2.Value = 0;
            app.MinEditField_2Label.Visible = false;
            app.MinEditField_2.Visible = false;
            app.MinEditField_2.Value = 0;
            app.RangeofIntervalLabel.Visible = false;
            % Iterations
            app.IterationsLabel.Visible = false;
            app.IterationsEditField.Visible = false;
            app.IterationsEditField.Value = 1;
            % Derivative
            app.DerivativeLabel.Visible = false;
            app.Label2.Visible = false;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            func(app);
            % by default is selected Nolinearequation and Biseccion
            app.ListBox_2.Value = "Bisection";
            app.ListBox_2.Visible = true;
            app.UIAxes.Visible = true;
            % Ecuation
            app.EcuationEditField.Visible = true;
            app.EcuationxLabel.Visible = true;
            % Range
            app.MaxEditField_2.Visible = true;
            app.MaxEditField_2Label.Visible = true;
            app.MinEditField_2Label.Visible = true;
            app.MinEditField_2.Visible = true;
            app.RangeofIntervalLabel.Visible = true;
            % Iterations
            app.IterationsLabel.Visible = true;
            app.IterationsEditField.Visible = true;
            % Graph
            app.UIAxes.Visible = true;
        end

        % Selection changed function: 
        % ChooseyourtypeofproblemButtonGroup
        function ChooseyourtypeofproblemButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseyourtypeofproblemButtonGroup.SelectedObject;
            % Everything disappears
            func(app);
            % Only what is selected appears
            switch(selectedButton)
                case app.NolinearequationButton_2
                    % by default is selected Biseccion
                    app.ListBox_2.Visible=true;
                    app.ListBox_2.Value = "Bisection";
                    % objects that it need
                    % Ecuation
                    app.EcuationEditField.Visible = true;
                    app.EcuationxLabel.Visible = true;
                    % Range
                    app.MaxEditField_2.Visible = true;
                    app.MaxEditField_2Label.Visible = true;
                    app.MinEditField_2Label.Visible = true;
                    app.MinEditField_2.Visible = true;
                    app.RangeofIntervalLabel.Visible = true;
                    % Iterations
                    app.IterationsLabel.Visible = true;
                    app.IterationsEditField.Visible = true;
                    % Graph
                    app.UIAxes.Visible = true;
                case app.NumericalintegrationButton_2
                    app.ListBox_3.Visible=true;
                    % by default is selected Trapezoidal multiple
                    app.ListBox_3.Value = "Trapezoidal multiple";
                    % missing code
                case app.NolinearequationsystemButton_2
                    app.ListBox.Visible=true;
                    % by default is selected Gauss-Seidel
                    app.ListBox.Value = "Gauss-Seidel";
                    % objects that it need
                    % Tables
                    app.UITable.Visible = true;
                    app.UITable2.Visible = true;
                    app.AXLabel.Visible = true;
                    app.BLabel.Visible = true;
                    % Iterations
                    app.IterationsLabel.Visible = true;
                    app.IterationsEditField.Visible = true;
                    % x#
                    app.XSpinner.Visible = true;
                    app.XSpinnerLabel.Visible = true;
                case app.DiferentialequationsystemButton_2
                    app.ListBox_4.Visible=true;
                    % by default is selected Euler
                    app.ListBox_4.Value = "Euler";
                    % missing code
            end
        end

        % Value changed function: ListBox_4
        function ListBox_4ValueChanged(app, event)
            value = app.ListBox_4.Value;
            % Everything disappears
            func(app);
            % objects that all functions need
            app.ListBox_4.Visible = true;
            % objects of each function
            if(value == "Euler")
                % missing code
            elseif(value == "Clasico Runge-kutta")
                % missing code
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            % Everything disappears
            func(app);
            % objects that all functions need
            app.ListBox.Visible = true;
            % Tables
            app.UITable.Visible = true;
            app.UITable2.Visible = true;
            app.AXLabel.Visible = true;
            app.BLabel.Visible = true;
            % Iterations
            app.IterationsLabel.Visible = true;
            app.IterationsEditField.Visible = true;
            % x#
            app.XSpinner.Visible = true;
            app.XSpinnerLabel.Visible = true;
            % objects of each function
            if(value == "Gauss-Seidel")
                %
            elseif(value == "Newton-Raphson")
                % missing code
            end
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            % Everything disappears
            func(app);
            % objects that all functions need
            app.ListBox_2.Visible = true;
            % Ecuation
            app.EcuationEditField.Visible = true;
            app.EcuationxLabel.Visible = true;
            % Iterations
            app.IterationsLabel.Visible = true;
            app.IterationsEditField.Visible = true;
            % Graph
            app.UIAxes.Visible = true;
            % objects of each function
            if(value == "Bisection" || value == "Regular Falsi")
                % Range
                app.MaxEditField_2.Visible = true;
                app.MaxEditField_2Label.Visible = true;
                app.MinEditField_2Label.Visible = true;
                app.MinEditField_2.Visible = true;
                app.RangeofIntervalLabel.Visible = true;
            elseif(value == "Simple Iteration fix point")
                % Initial value
                app.InitialvalueEditFieldLabel.Visible = true;
                app.InitialvalueEditField.Visible = true;
            elseif(value =="Newton- Raphson")
                % Initial value
                app.InitialvalueEditFieldLabel.Visible = true;
                app.InitialvalueEditField.Visible = true;
                % Derivative
                app.DerivativeLabel.Visible = true;
            end
        end

        % Value changed function: ListBox_3
        function ListBox_3ValueChanged(app, event)
            value = app.ListBox_3.Value;
            % Everything disappears
            func(app);
            % objects that all functions need
            app.ListBox_3.Visible = true;
            % objects of each function
            if(value == "Trapezoidal multiple")
                % missing code
            elseif(value == "Simpson 3/8 multiple")
                % missing code
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % It is assumed that the values entered are valid
            switch(app.ChooseyourtypeofproblemButtonGroup.SelectedObject)
                case app.NolinearequationButton_2
                    switch(app.ListBox_2.Value)
                        case "Bisection"
                            %
                        case "Regular Falsi"
                            %
                        case "Simple Iteration fix point"
                            %
                        case "Newton- Raphson"
                            %
                    end
                case app.NumericalintegrationButton_2
                    switch(app.ListBox_3.Value)
                        case "Trapezoidal multiple"
                            % missing code
                        case "Simpson 3/8 multiple"
                            % missing code
                    end
                case app.NolinearequationsystemButton_2
                    switch(app.ListBox.Value)
                        case "Gauss-Seidel"
                            % missing code
                        case "Newton-Raphson"
                            % missing code
                    end
                case app.DiferentialequationsystemButton_2
                    switch(app.ListBox_4.Value)
                        case "Euler"
                            % missing code
                        case "Clasico Runge-kutta"
                            % missing code
                    end
            end
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            value = app.XSpinner.Value;
            % each time the value is changed, the tables are updated
            app.UITable.Data = zeros(value);
            app.UITable2.Data = zeros(value,1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumiiSolveLabel = uilabel(app.UIFigure);
            app.NumiiSolveLabel.Text = 'Numii Solve';
            app.MathproblemIdoitforyouLabel = uilabel(app.UIFigure);
            app.MathproblemIdoitforyouLabel.Text = 'Math problem? I do it for you!';
            app.ChooseyourtypeofproblemButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseyourtypeofproblemButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseyourtypeofproblemButtonGroupSelectionChanged, true);
            app.ChooseyourtypeofproblemButtonGroup.Title = 'Choose your type of problem:';
            app.NolinearequationButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.NolinearequationButton_2.Text = 'No linear equation';
            app.NolinearequationButton_2.Value = true;
            app.NumericalintegrationButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.NumericalintegrationButton_2.Text = 'Numerical integration';
            app.NolinearequationsystemButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.NolinearequationsystemButton_2.Text = 'No linear equation system';
            app.DiferentialequationsystemButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.DiferentialequationsystemButton_2.Text = 'Diferential equation system';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Gauss-Seidel', 'Newton-Raphson'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Newton-Raphson';
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {'Bisection', 'Regular Falsi', 'Simple Iteration fix point', 'Newton- Raphson'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = 'Bisection';
            app.ListBox_3 = uilistbox(app.UIFigure);
            app.ListBox_3.Items = {'Trapezoidal multiple', 'Simpson 3/8 multiple'};
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
            app.ListBox_3.Value = 'Trapezoidal multiple';
            app.ListBox_4 = uilistbox(app.UIFigure);
            app.ListBox_4.Items = {'Euler', 'Clasico Runge-kutta'};
            app.ListBox_4.ValueChangedFcn = createCallbackFcn(app, @ListBox_4ValueChanged, true);
            app.ListBox_4.Value = 'Euler';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Error')
            xlabel(app.UIAxes, 'Iterations')
            ylabel(app.UIAxes, 'Ea')
            app.EcuationEditField = uieditfield(app.UIFigure, 'text');
            app.RangeofIntervalLabel = uilabel(app.UIFigure);
            app.RangeofIntervalLabel.Text = 'Range of Interval';
            app.MinEditField_2Label = uilabel(app.UIFigure);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField_2Label = uilabel(app.UIFigure);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.IterationsLabel = uilabel(app.UIFigure);
            app.IterationsLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.Value = 1;
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.RaizLabel = uilabel(app.UIFigure);
            app.RaizLabel.Text = 'Raiz';
            app.Label = uilabel(app.UIFigure);
            app.EaLabel = uilabel(app.UIFigure);
            app.EaLabel.Text = 'Ea';
            app.Label_2 = uilabel(app.UIFigure);
            app.EcuationxLabel = uilabel(app.UIFigure);
            app.EcuationxLabel.Text = 'Ecuation @(x)';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.XSpinnerLabel = uilabel(app.UIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.UIFigure);
            app.XSpinner.Limits = [2 Inf];
            app.XSpinner.RoundFractionalValues = 'on';
            app.XSpinner.ValueDisplayFormat = '%11g';
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.XSpinner.Value = 2;
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.InitialvalueEditFieldLabel = uilabel(app.UIFigure);
            app.InitialvalueEditFieldLabel.Text = 'Initial value';
            app.InitialvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.DerivativeLabel = uilabel(app.UIFigure);
            app.DerivativeLabel.Text = 'Derivative';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.AXLabel = uilabel(app.UIFigure);
            app.AXLabel.Text = 'AX#';
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B';
        end
    end

    methods (Access = public)

        % Construct app
        function app = methodSelector

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end